classdef SDOCT_main_D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SiOCT                           matlab.ui.Figure
        messageEditField                matlab.ui.control.EditField
        messageEditFieldLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TrainTestTab                    matlab.ui.container.Tab
        CancelButton                    matlab.ui.control.Button
        Step1SelectDatabasePanel        matlab.ui.container.Panel
        DatabaseDropDown                matlab.ui.control.DropDown
        DatabaseDropDownLabel           matlab.ui.control.Label
        Step3TrainPanel                 matlab.ui.container.Panel
        ClassesNumPanel                 matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        BalanceClassesButton            matlab.ui.control.Button
        CalButton                       matlab.ui.control.Button
        AMDEditField                    matlab.ui.control.NumericEditField
        AMDLabel                        matlab.ui.control.Label
        DMEEditField                    matlab.ui.control.NumericEditField
        DMELabel                        matlab.ui.control.Label
        NormalEditField                 matlab.ui.control.NumericEditField
        NormalLabel                     matlab.ui.control.Label
        EnableFeatureSelectionCheckBox  matlab.ui.control.CheckBox
        EnsembleModelCheckBox           matlab.ui.control.CheckBox
        TestEnableCheckBox              matlab.ui.control.CheckBox
        IterationNumberEditField        matlab.ui.control.NumericEditField
        IterationNumberEditFieldLabel   matlab.ui.control.Label
        RunTrainButton                  matlab.ui.control.Button
        naiveBayseCheckBox              matlab.ui.control.CheckBox
        RandomForestCheckBox            matlab.ui.control.CheckBox
        NeuralNetworkCheckBox           matlab.ui.control.CheckBox
        KNNCheckBox                     matlab.ui.control.CheckBox
        SVMCheckBox                     matlab.ui.control.CheckBox
        DecisionTreeCheckBox            matlab.ui.control.CheckBox
        TestPartitionSlider             matlab.ui.control.Slider
        TestPartitionSliderLabel        matlab.ui.control.Label
        Step2LayersandFeatureDetectionPanel  matlab.ui.container.Panel
        FeatureSelectionButton          matlab.ui.control.Button
        NormalizedFeaturesCheckBox      matlab.ui.control.CheckBox
        NormMethodButtonGroup           matlab.ui.container.ButtonGroup
        rangeButton                     matlab.ui.control.RadioButton
        zscoreButton                    matlab.ui.control.RadioButton
        FeatureSelMethodButtonGroup     matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        EditField_7                     matlab.ui.control.NumericEditField
        FS_NumPowerEditField            matlab.ui.control.NumericEditField
        FS_NumLabel                     matlab.ui.control.Label
        Mix_NumEditField                matlab.ui.control.NumericEditField
        Mix_NumEditFieldLabel           matlab.ui.control.Label
        MixButton                       matlab.ui.control.RadioButton
        ReliefButton                    matlab.ui.control.RadioButton
        NCAButton                       matlab.ui.control.RadioButton
        Chi2Button                      matlab.ui.control.RadioButton
        MRMRButton                      matlab.ui.control.RadioButton
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        AMDImNoEditField                matlab.ui.control.NumericEditField
        AMDImNoEditFieldLabel           matlab.ui.control.Label
        DMEImNoEditField                matlab.ui.control.NumericEditField
        DMEImNoLabel                    matlab.ui.control.Label
        AMDCheckBox                     matlab.ui.control.CheckBox
        DMECheckBox                     matlab.ui.control.CheckBox
        NormalCheckBox                  matlab.ui.control.CheckBox
        NormalImNoEditField             matlab.ui.control.NumericEditField
        NormalImNoEditFieldLabel        matlab.ui.control.Label
        EnablesubroutinplotsCheckBox    matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        EnableFeatureExtractorCheckBox  matlab.ui.control.CheckBox
        EnableimshowCheckBox            matlab.ui.control.CheckBox
        EnableLayerDetectCheckBox       matlab.ui.control.CheckBox
        AnalysisTab                     matlab.ui.container.Tab
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end


    properties (Access = public)
        NormalTargets, DMETargets, AMDTargets;
        AMDFeatures,DMEFeatures,NormalFeatures;
        R_DT,R_SVM,R_RF,R_KNN,R_NN,R_nB,R_RM,R_Ens;
        FeaturesM; TargetV; selectedidx; 
        FselectFlag = 0; FNames; SortedFeatures; SortedScore; FeaturesMPCA;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off')
            addpath('.\functions')
            app.messageEditField.Value = '';
        end

        % Callback function
        function DatabaseSelctorButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatabaseSelctorButtonGroup.SelectedObject;

        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            value = app.DatabaseDropDown.Value;

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.messageEditField.Value = '';
            pause(0.001);
            cd 'D:\My Works\OCT\Last\MycodesDuke'
            addpath('.\functions')
            Figures = findobj('Type','Figure');
            close(Figures)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Read or Load

            switch app.DatabaseDropDown.Value
                case 'Read Duke DB'
                    app.messageEditField.Value = 'Duke DB Reading is running';
                    pause(0.001)
                    [image1, image2, image3] =read_dataset(app);
                    app.messageEditField.Value = 'Reading of Duke DB was completed';
                    pause(0.001)
                case 'Load Duke DB'
                    app.messageEditField.Value = 'Duke DB Loading  is running';
                    pause(0.001)
                    load imagesNor.mat;
                    load imagesDME.mat;
                    load imagesAMD.mat;
                    if app.NormalImNoEditField.Value ~=0
                        temp1{1} = image1{app.NormalImNoEditField.Value*app.EditField.Value};
                        image1=temp1;
                    end
                    if app.DMEImNoEditField.Value ~=0
                        temp2{1} = image2{app.DMEImNoEditField.Value*app.EditField_2.Value};
                        image2=temp2;
                    end
                    if app.AMDImNoEditField.Value ~=0
                        temp3{1} = image3{app.AMDImNoEditField.Value*app.EditField_3.Value};
                        image3=temp3;
                    end
                    app.messageEditField.Value = 'Loading of Duke DB was completed';
                    pause(0.001)
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %             Layer Detection Routin
            if app.EnableLayerDetectCheckBox.Value == 1
                app.messageEditField.Value = 'Layer detection routing is running ...';
                pause(0.001)
                [layer_lines1, layer_lines2, layer_lines3] = Duke_layer_lines(app,image1, image2, image3);
                app.messageEditField.Value = 'Layer detection routing was completed';
                pause(0.001)
            else
                app.messageEditField.Value = 'Detected Layeres are being loaded';
                pause(0.001)
                switch app.DatabaseDropDown.Value
                    case 'Read Duke DB'
                        load linesNor_D.mat
                        load linesDME_D.mat
                        load linesAMD_D.mat
                    case 'Load Duke DB'
                        load linesNor_D.mat
                        load linesDME_D.mat
                        load linesAMD_D.mat
                        if app.NormalImNoEditField.Value ~=0
                            temp1{1} = layer_lines1{app.NormalImNoEditField.Value*app.EditField.Value};
                            layer_lines1=temp1;
                        end
                        if app.DMEImNoEditField.Value ~=0
                            temp2{1} = layer_lines2{app.DMEImNoEditField.Value*app.EditField_2.Value};
                            layer_lines2=temp2;
                        end
                        if app.AMDImNoEditField.Value ~=0
                            temp3{1} = layer_lines3{app.AMDImNoEditField.Value*app.EditField_3.Value};
                            layer_lines3=temp3;
                        end
                        app.messageEditField.Value = 'Detected Layeres were loaded';
                        pause(0.001)
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %             Feature Extraction Routin
            if app.EnableFeatureExtractorCheckBox.Value == 1
                Normalexclude =[];  DMEexclude =[]; AMDexclude =[];
                %                 app.messageEditField.Value = ' ';
                if app.NormalCheckBox.Value ==1
                    ndata1=length(layer_lines1);
                    app.messageEditField.Value = 'Features are being extracted from Normal images';
                    pause(0.001)
                    %                     disp('Features are being extracted from Normal images')
                    c =0; cc = 0; flag =0; 
                    for i=1 : ndata1
                        disp(num2str(i))
                        if length(layer_lines1{i})>10
                            c = c+1;
                            F1 = FV_extraction4(layer_lines1{i}); % Feature Selection
                            if i == 1 && flag == 0
                                FeaturesNormal = zeros(ndata1,size(F1,2));
                                flag = 1;
                            end
                            FeaturesNormal(c,:) = F1;
                        else
                            disp(['Normal image, number ' num2str(i), ' was excluded']);
                            cc=cc+1;
                            Normalexclude(cc) = i;
                        end
                    end
                    FeaturesNormal = FeaturesNormal(1:end-cc,:);
                    TargetsNormal=ones(size(FeaturesNormal,1),1);
                    app.NormalFeatures=FeaturesNormal;
                    if app.NormalImNoEditField.Value ==0
                        switch app.DatabaseDropDown.Value
                            case 'Read Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresNor FeaturesNormal TargetsNormal Normalexclude
                                %                                 disp('Feautures from Normal images were created and save')
                                app.messageEditField.Value = 'Feautures from Normal images were created and saved';
                            case 'Load Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresNor FeaturesNormal TargetsNormal Normalexclude
                                %                                 disp('Feautures from Normal images were created and saved')
                                app.messageEditField.Value = 'Feautures from Normal images were created and saved';
                                pause(0.001)
                        end
                    end
                end
                if app.DMECheckBox.Value==1
                    ndata2=length(layer_lines2);
                    %                     disp('Features are being extracted from DME images')
                    app.messageEditField.Value = 'Features are being extracted from DME images';
                    pause(0.001)
                    c = 0; cc=0; flag = 0;
                    for i = 1 : ndata2
                        disp(num2str(i))
                        if length(layer_lines2{i})>10
                            c=c+1;
                            F1 = FV_extraction4(layer_lines2{i}); % Feature Selection
                            if i == 1 && flag == 0
                                FeaturesDME = zeros(ndata2,size(F1,2));
                                flag = 1;
                            end
                            FeaturesDME(c,:) = F1;
                        else
                            disp(['DME image, number ' num2str(i), ' was excluded']);
                            cc=cc+1;
                            DMEexclude(cc) = i;
                        end

                    end
                    FeaturesDME = FeaturesDME(1:end-cc,:);
                    TargetsDME = 2*ones(size(FeaturesDME,1),1);
                    app.DMEFeatures=FeaturesDME;
                    if app.DMEImNoEditField.Value ==0
                        switch app.DatabaseDropDown.Value
                            case 'Read Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresDME  FeaturesDME TargetsDME DMEexclude
                                disp('Feautures from DME images were created and saved')
                            case 'Load Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresDME  FeaturesDME TargetsDME DMEexclude
                                disp('Feautures from DME images were created and saved')
                        end
                    end
                end
                if app.AMDCheckBox.Value == 1
                    ndata3=length(layer_lines3);
                    disp('Features are being extracted from AMD images')

                    c = 0; cc=0; flag = 0;
                    for i= 1: ndata3
                        disp(num2str(i))
                        if length(layer_lines3{i})>10
                            c=c+1;
                            F1 = FV_extraction4(layer_lines3{i}); % Feature Selection
                            if i == 1 && flag == 0
                                FeaturesAMD = zeros(ndata3,size(F1,2));
                                flag = 1;
                            end
                            FeaturesAMD(c,:) = F1;
                        else
                            disp(['AMD image, number ' num2str(i), ' was excluded']);
                            cc=cc+1;
                            AMDexclude(cc) = i;
                        end
                    end
                    FeaturesAMD = FeaturesAMD(1:end-cc,:);
                    app.AMDFeatures = FeaturesAMD;
                    TargetsAMD = 3*ones(size(FeaturesAMD,1),1);
                    if app.AMDImNoEditField.Value ==0
                        switch app.DatabaseDropDown.Value
                            case 'Read Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresAMD FeaturesAMD TargetsAMD AMDexclude
                                %                                 disp('Feautures from AMD images were created and saved')
                                app.messageEditField.Value = 'Feautures from AMD images were created and saved';
                                pause(0.001)
                            case 'Load Duke DB'
                                cd 'D:\My Works\OCT\Last\MycodesDuke'
                                save data_featuresAMD FeaturesAMD TargetsAMD AMDexclude
                                app.messageEditField.Value = 'Feautures from AMD images were created and saved';
                                pause(0.001)
                                %                                 disp('Feautures from AMD images were created and saved')
                        end
                    end
                end
                
            else
                if app.NormalCheckBox.Value == 1
                    load data_featuresNor.mat
                    if app.NormalImNoEditField.Value ==0
                        app.NormalTargets = TargetsNormal;
                        app.NormalFeatures = FeaturesNormal;
                    else
                        app.NormalTargets = TargetsNormal(app.NormalImNoEditField.Value*app.EditField.Value,1);
                        app.NormalFeatures = FeaturesNormal(app.NormalImNoEditField.Value*app.EditField.Value,:);
                    end
                end
                if app.DMECheckBox.Value == 1
                    load data_featuresDME.mat
                    if app.DMEImNoEditField.Value ==0
                        app.DMETargets = TargetsDME;
                        app.DMEFeatures = FeaturesDME;
                    else
                        app.DMETargets = TargetsDME(app.DMEImNoEditField.Value*app.EditField_2.Value,1);
                        app.DMEFeatures = FeaturesDME(app.DMEImNoEditField.Value*app.EditField_2.Value,:);
                    end
                end
                if app.AMDCheckBox.Value == 1
                    load data_featuresAMD.mat
                    if app.AMDImNoEditField.Value ==0
                        app.AMDTargets = TargetsAMD;
                        app.AMDFeatures = FeaturesAMD;
                    else
                        app.AMDTargets = TargetsAMD(app.AMDImNoEditField.Value*app.EditField_3.Value,1);
                        app.AMDFeatures = FeaturesAMD(app.AMDImNoEditField.Value*app.EditField_3.Value,:);
                    end
                end

            end
            app.FNames = featuresName;
           
            app.FeaturesM= [app.NormalFeatures;
                app.DMEFeatures;
                app.AMDFeatures];
            app.TargetV = [app.NormalTargets;
                app.DMETargets;
                app.AMDTargets;];
            app.messageEditField.Value = 'Feautures were loaded';
            pause(0.001)
            
        end

        % Value changed function: EnableLayerDetectCheckBox
        function EnableLayerDetectCheckBoxValueChanged(app, event)
            value = app.EnableLayerDetectCheckBox.Value;
        end

        % Value changed function: EnableimshowCheckBox
        function EnableimshowCheckBoxValueChanged(app, event)
            value = app.EnableimshowCheckBox.Value;

        end

        % Value changed function: EnableFeatureExtractorCheckBox
        function EnableFeatureExtractorCheckBoxValueChanged(app, event)
            value = app.EnableFeatureExtractorCheckBox.Value;

        end

        % Value changed function: DecisionTreeCheckBox
        function DecisionTreeCheckBoxValueChanged(app, event)
            value = app.DecisionTreeCheckBox.Value;

        end

        % Value changed function: SVMCheckBox
        function SVMCheckBoxValueChanged(app, event)
            value = app.SVMCheckBox.Value;

        end

        % Value changed function: KNNCheckBox
        function KNNCheckBoxValueChanged(app, event)
            value = app.KNNCheckBox.Value;

        end

        % Value changed function: NeuralNetworkCheckBox
        function NeuralNetworkCheckBoxValueChanged(app, event)
            value = app.NeuralNetworkCheckBox.Value;

        end

        % Value changed function: RandomForestCheckBox
        function RandomForestCheckBoxValueChanged(app, event)
            value = app.RandomForestCheckBox.Value;

        end

        % Value changed function: naiveBayseCheckBox
        function naiveBayseCheckBoxValueChanged(app, event)
            value = app.naiveBayseCheckBox.Value;

        end

        % Button pushed function: RunTrainButton
        function RunTrainButtonPushed(app, event)
            app.messageEditField.Value = 'Train and test routin is running ...';
            pause(0.001);
            methods = [app.DecisionTreeCheckBox.Value;
                app.SVMCheckBox.Value;
                app.KNNCheckBox.Value;
                app.NeuralNetworkCheckBox.Value;
                app.RandomForestCheckBox.Value;
                app.naiveBayseCheckBox.Value;
                app.EnsembleModelCheckBox.Value];
            iteration = app.IterationNumberEditField.Value;
            testen = app.TestEnableCheckBox.Value;
            app.FeaturesM = real(app.FeaturesM);
            if app.EnableFeatureSelectionCheckBox.Value == 1
                if strcmp(app.FeatureSelMethodButtonGroup.SelectedObject.Text,'PCA')
                    SelectedFeatures = app.FeaturesMPCA;
                    Targets = app.TargetV;
                else
                    SelectedFeatures = app.FeaturesM(:,app.selectedidx);
                    Targets = app.TargetV;
                end
            else
                SelectedFeatures = app.FeaturesM;
                Targets = app.TargetV;
            end
            [removedimageind,~]=find(isinf(SelectedFeatures));
            SelectedFeatures(removedimageind,:)=[];
            Targets(removedimageind,:)=[];
            if app.NormalizedFeaturesCheckBox.Value == 1
                SelectedFeatures = normalize(SelectedFeatures,...
                    app.NormMethodButtonGroup.SelectedObject.Text);
            end
            setparts = [1-(app.TestPartitionSlider.Value)/100,0,(app.TestPartitionSlider.Value)/100];
            [R_DT1, R_SVM1, R_RF1, R_KNN1, R_NN1, R_nB1,R_Ens1,rand_pos,testIndout] ...
                = train_test(SelectedFeatures,Targets,iteration,methods,setparts,testen);
            save ShufflePositions rand_pos testIndout removedimageind
%             app.R_DT = R_DT1;
%             app.R_SVM = R_SVM1;
%             app.R_RF = R_RF1;
%             app.R_KNN = R_KNN1;
%             app.R_NN = R_NN1;
%             app.R_nB = R_nB1;
%             app.R_Ens=R_Ens1;
            classnumber = 3;
            [m, stdV] = meanVarofstruct(R_DT1,iteration,classnumber);
            R_DT1.m = m;
            R_DT1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_SVM1,iteration,classnumber);
            R_SVM1.m = m;
            R_SVM1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_RF1,iteration,classnumber);
            R_RF1.m = m;
            R_RF1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_NN1,iteration,classnumber);
            R_NN1.m = m;
            R_NN1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_KNN1,iteration,classnumber);
            R_KNN1.m = m;
            R_KNN1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_nB1,iteration,classnumber);
            R_nB1.m = m;
            R_nB1.stdV = stdV;
            [m, stdV] = meanVarofstruct(R_Ens1,iteration,classnumber);
            R_Ens1.m = m;
            R_Ens1.stdV = stdV;

            app.R_DT = R_DT1;
            app.R_SVM = R_SVM1;
            app.R_RF = R_RF1;
            app.R_KNN = R_KNN1;
            app.R_NN = R_NN1;
            app.R_nB = R_nB1;
            app.R_Ens = R_Ens1;

            save total_method R_DT1 R_SVM1 R_RF1 R_KNN1 R_NN1 R_nB1 R_Ens1
            app.messageEditField.Value = 'Train and Test routin were finished';
            %
            if sum(methods(1:7))==7 && testen == 1
                plotBars(classnumber,R_RF1, R_SVM1, R_DT1, R_NN1, R_KNN1, R_nB1, R_Ens1);

            end

        end

        % Value changed function: TestPartitionSlider
        function TestPartitionSliderValueChanged(app, event)
            value = app.TestPartitionSlider.Value;

        end

        % Value changed function: IterationNumberEditField
        function IterationNumberEditFieldValueChanged(app, event)
            value = app.IterationNumberEditField.Value;

        end

        % Value changed function: TestEnableCheckBox
        function TestEnableCheckBoxValueChanged(app, event)
            value = app.TestEnableCheckBox.Value;

        end

        % Value changed function: EnablesubroutinplotsCheckBox
        function EnablesubroutinplotsCheckBoxValueChanged(app, event)
            value = app.EnablesubroutinplotsCheckBox.Value;

        end

        % Value changed function: NormalCheckBox
        function NormalCheckBoxValueChanged(app, event)
            value = app.NormalCheckBox.Value;

        end

        % Value changed function: DMECheckBox
        function DMECheckBoxValueChanged(app, event)
            value = app.DMECheckBox.Value;

        end

        % Value changed function: AMDCheckBox
        function AMDCheckBoxValueChanged(app, event)
            value = app.AMDCheckBox.Value;

        end

        % Value changed function: NormalImNoEditField
        function NormalImNoEditFieldValueChanged(app, event)
            value = app.NormalImNoEditField.Value;

        end

        % Value changed function: DMEImNoEditField
        function DMEImNoEditFieldValueChanged(app, event)
            value = app.DMEImNoEditField.Value;

        end

        % Value changed function: AMDImNoEditField
        function AMDImNoEditFieldValueChanged(app, event)
            value = app.AMDImNoEditField.Value;

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            error('Program terminated  by user')
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;

        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;

        end

        % Value changed function: EnsembleModelCheckBox
        function EnsembleModelCheckBoxValueChanged(app, event)
            value = app.EnsembleModelCheckBox.Value;

        end

        % Value changed function: EnableFeatureSelectionCheckBox
        function EnableFeatureSelectionCheckBoxValueChanged(app, event)
            value = app.EnableFeatureSelectionCheckBox.Value;
            if value == 0 && app.FselectFlag ==1
                app.EnableFeatureSelectionCheckBox.Value = 0;
                app.messageEditField.Value = 'Using the total features ...';
            end
            if value == 1 && app.FselectFlag ==1
                app.EnableFeatureSelectionCheckBox.Value = 1;
                app.messageEditField.Value = 'Using the selected features ...';
            end
            if value == 1 && app.FselectFlag ==0
                app.messageEditField.Value = 'First push <Feature Selection> button';
                app.EnableFeatureSelectionCheckBox.Value = 0;
            end

        end

        % Button pushed function: FeatureSelectionButton
        function FeatureSelectionButtonPushed(app, event)
            SelectedFeatures = real(app.FeaturesM);
            if app.NormalizedFeaturesCheckBox.Value == 1
                SelectedFeatures = normalize(app.FeaturesM,...
                    app.NormMethodButtonGroup.SelectedObject.Text);
            end
            opt = app.FeatureSelMethodButtonGroup.SelectedObject.Text;
            FS_Num = app.FS_NumPowerEditField.Value;
            app.messageEditField.Value = 'Feature Selection is running ... ';
            pause(0.001)
            if strcmp(opt,'MRMR') || strcmp(opt,'Mix')
                disp('MRMR is running');
                pause(0.001)
                [idxfscmrmr,scores] = fscmrmr(SelectedFeatures,app.TargetV);
                
                app.selectedidx = idxfscmrmr(1:FS_Num);
                app.SortedScore = scores(app.selectedidx)';
                totpower = sum(scores(:));
                selpower = sum(app.SortedScore(:));
                power_percent = round(100*selpower/totpower,1);
            end
            if strcmp(opt,'Chi-2') || strcmp(opt,'Mix')
                disp('Chi-2 is running');
                pause(0.001)
                [idxfscchi2,scores] = fscchi2(SelectedFeatures,app.TargetV);
                scores(isinf(scores)) = 0;
                scores(isnan(scores)) = 0;
                app.selectedidx = idxfscchi2(1:FS_Num);
                app.SortedScore = scores(app.selectedidx)';
                totpower = sum(scores(:));
                selpower = sum(app.SortedScore(:));
                power_percent = round(100*selpower/totpower,1);
            end
            if strcmp(opt,'NCA') || strcmp(opt,'Mix')
                disp('NCA is running');
                pause(0.001)
                nca = fscnca(SelectedFeatures,app.TargetV);
                [~,idxnca] = sort(nca.FeatureWeights,'descend');
                app.selectedidx = idxnca(1:FS_Num)';
                app.SortedScore = nca.FeatureWeights(app.selectedidx);
                totpower = sum(nca.FeatureWeights(:));
                selpower = sum(app.SortedScore(:));
                power_percent = round(100*selpower/totpower,1);
            end
            if strcmp(opt,'Relief') || strcmp(opt,'Mix')
                disp('Relief is running');
                pause(0.001)
                [idxrelieff,weights] = relieff(SelectedFeatures,app.TargetV,3);
                app.selectedidx = idxrelieff(1:FS_Num);
                app.SortedScore = weights(app.selectedidx)';
                
                totpower = sum(weights(:));
                selpower = sum(app.SortedScore(:));
                power_percent = round(100*selpower/totpower,1);
            end
            if strcmp(opt,'PCA') 
                disp('PCA is running');
                pause(0.001)
                [coeff, score, latent] = pca(SelectedFeatures);
%                 covarianceMatrix = cov(SelectedFeatures-mean((SelectedFeatures)));
%                 [~,D] = eig(covarianceMatrix);
%                 lamdas = abs(diag(D));
                app.FeaturesMPCA = score(:,1:FS_Num);
                totpower = sum(latent(:));
                selpower = sum(latent(1:FS_Num));
                power_percent = round(100*selpower/totpower,1);
                app.EditField_7.Value = power_percent;
                app.messageEditField.Value = 'PCA was done';
            end
            if strcmp(opt,'Mix')
                Mix_Num = app.Mix_NumEditField.Value;
                selectedidx = [idxfscmrmr(1:Mix_Num),idxfscchi2(1:Mix_Num),idxnca(1:Mix_Num)',idxrelieff(1:Mix_Num)];
                app.selectedidx = unique(selectedidx);
            end

            disp('Final feature index was created');
            app.FselectFlag = 1;
            if ~strcmp(opt,'Mix') && ~strcmp(opt,'PCA')
            app.SortedFeatures =app.FNames(app.selectedidx);
            SortedFeaturesName = (app.SortedFeatures)';
            Selectedscores = app.SortedScore;
%             SortedFeaturesName
%             Selectedscores
            T = table(SortedFeaturesName,Selectedscores);
            filename = 'SortedFeatures.xlsx';
            writetable(T,filename,'Sheet',1,'WriteMode','overwritesheet')
            app.EditField_7.Value = power_percent;
            end
            app.messageEditField.Value = ['Efficient features: ' num2str(app.selectedidx)];
        end

        % Value changed function: messageEditField
        function messageEditFieldValueChanged(app, event)
            value = app.messageEditField.Value;

        end

        % Value changed function: NormalEditField
        function NormalEditFieldValueChanged(app, event)
            value = app.NormalEditField.Value;

        end

        % Value changed function: DMEEditField
        function DMEEditFieldValueChanged(app, event)
            value = app.DMEEditField.Value;

        end

        % Value changed function: AMDEditField
        function AMDEditFieldValueChanged(app, event)
            value = app.AMDEditField.Value;

        end

        % Button pushed function: CalButton
        function CalButtonPushed(app, event)
            if ~isempty(app.TargetV)
                dataper=tabulate(app.TargetV);
                app.NormalEditField.Value = round(dataper(1,3),1);
                app.DMEEditField.Value = round(dataper(2,3),1);
                app.AMDEditField.Value = round(dataper(3,3),1);
                app.EditField_4.Value = round(dataper(1,2));
                app.EditField_5.Value = round(dataper(2,2));
                app.EditField_6.Value = round(dataper(3,2));
            else
                app.messageEditField.Value = 'First load Feature Vector';
            end
        end

        % Button pushed function: BalanceClassesButton
        function BalanceClassesButtonPushed(app, event)
            if ~isempty(app.TargetV)
                dataper=tabulate(app.TargetV);
                minlen = min(dataper(:,2));
                size(app.NormalFeatures)
                app.FeaturesM= [app.NormalFeatures(1:minlen,:);
                    app.DMEFeatures(1:minlen,:);
                    app.AMDFeatures(1:minlen,:)];
                app.TargetV = [app.NormalTargets(1:minlen,:);
                    app.DMETargets(1:minlen,:);
                    app.AMDTargets(1:minlen,:);];
                dataper=tabulate(app.TargetV);

                app.NormalEditField.Value = round(dataper(1,3),1);
                app.DMEEditField.Value = round(dataper(2,3),1);
                app.AMDEditField.Value = round(dataper(3,3),1);
                app.EditField_4.Value = round(dataper(1,2));
                app.EditField_5.Value = round(dataper(2,2));
                app.EditField_6.Value = round(dataper(3,2));
            else
                app.messageEditField.Value = 'First load Feature Vector';
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;

        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;

        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;

        end

        % Value changed function: NormalizedFeaturesCheckBox
        function NormalizedFeaturesCheckBoxValueChanged(app, event)
            value = app.NormalizedFeaturesCheckBox.Value;

        end

        % Selection changed function: NormMethodButtonGroup
        function NormMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormMethodButtonGroup.SelectedObject;
        end

        % Selection changed function: FeatureSelMethodButtonGroup
        function FeatureSelMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.FeatureSelMethodButtonGroup.SelectedObject;

        end

        % Value changed function: Mix_NumEditField
        function Mix_NumEditFieldValueChanged(app, event)
            value = app.Mix_NumEditField.Value;

        end

        % Value changed function: FS_NumPowerEditField
        function FS_NumPowerEditFieldValueChanged(app, event)
            value = app.FS_NumPowerEditField.Value;

        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SiOCT = uifigure('Visible', 'off');
            app.SiOCT.Name = 'SiOCT';
            app.TabGroup = uitabgroup(app.SiOCT);
            app.TrainTestTab = uitab(app.TabGroup);
            app.TrainTestTab.Title = 'Train &Test';
            app.Step2LayersandFeatureDetectionPanel = uipanel(app.TrainTestTab);
            app.Step2LayersandFeatureDetectionPanel.Title = 'Step 2. (Layers and Feature Detection)';
            app.EnableLayerDetectCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.EnableLayerDetectCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableLayerDetectCheckBoxValueChanged, true);
            app.EnableLayerDetectCheckBox.Text = 'Enable Layer Detect';
            app.EnableimshowCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.EnableimshowCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableimshowCheckBoxValueChanged, true);
            app.EnableimshowCheckBox.Text = 'Enable imshow';
            app.EnableFeatureExtractorCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.EnableFeatureExtractorCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableFeatureExtractorCheckBoxValueChanged, true);
            app.EnableFeatureExtractorCheckBox.Text = 'Enable Feature Extractor';
            app.RunButton = uibutton(app.Step2LayersandFeatureDetectionPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EnablesubroutinplotsCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.EnablesubroutinplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablesubroutinplotsCheckBoxValueChanged, true);
            app.EnablesubroutinplotsCheckBox.Text = 'Enable subroutin plots';
            app.NormalImNoEditFieldLabel = uilabel(app.Step2LayersandFeatureDetectionPanel);
            app.NormalImNoEditFieldLabel.Text = 'Normal Im No';
            app.NormalImNoEditField = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.NormalImNoEditField.ValueChangedFcn = createCallbackFcn(app, @NormalImNoEditFieldValueChanged, true);
            app.NormalCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.NormalCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalCheckBoxValueChanged, true);
            app.NormalCheckBox.Text = 'Normal';
            app.NormalCheckBox.Value = true;
            app.DMECheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.DMECheckBox.ValueChangedFcn = createCallbackFcn(app, @DMECheckBoxValueChanged, true);
            app.DMECheckBox.Text = 'DME';
            app.DMECheckBox.Value = true;
            app.AMDCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.AMDCheckBox.ValueChangedFcn = createCallbackFcn(app, @AMDCheckBoxValueChanged, true);
            app.AMDCheckBox.Text = 'AMD';
            app.AMDCheckBox.Value = true;
            app.DMEImNoLabel = uilabel(app.Step2LayersandFeatureDetectionPanel);
            app.DMEImNoLabel.Text = 'DME Im No';
            app.DMEImNoEditField = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.DMEImNoEditField.ValueChangedFcn = createCallbackFcn(app, @DMEImNoEditFieldValueChanged, true);
            app.AMDImNoEditFieldLabel = uilabel(app.Step2LayersandFeatureDetectionPanel);
            app.AMDImNoEditFieldLabel.Text = 'AMD Im No';
            app.AMDImNoEditField = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.AMDImNoEditField.ValueChangedFcn = createCallbackFcn(app, @AMDImNoEditFieldValueChanged, true);
            app.EditField = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.Step2LayersandFeatureDetectionPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.FeatureSelMethodButtonGroup = uibuttongroup(app.Step2LayersandFeatureDetectionPanel);
            app.FeatureSelMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FeatureSelMethodButtonGroupSelectionChanged, true);
            app.FeatureSelMethodButtonGroup.Title = 'Feature Sel. Method';
            app.MRMRButton = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.MRMRButton.Text = 'MRMR';
            app.MRMRButton.Value = true;
            app.Chi2Button = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.Chi2Button.Text = 'Chi-2';
            app.NCAButton = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.NCAButton.Text = 'NCA';
            app.ReliefButton = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.ReliefButton.Text = 'Relief';
            app.MixButton = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.MixButton.Text = 'Mix';
            app.Mix_NumEditFieldLabel = uilabel(app.FeatureSelMethodButtonGroup);
            app.Mix_NumEditFieldLabel.Text = 'Mix_Num.';
            app.Mix_NumEditField = uieditfield(app.FeatureSelMethodButtonGroup, 'numeric');
            app.Mix_NumEditField.ValueChangedFcn = createCallbackFcn(app, @Mix_NumEditFieldValueChanged, true);
            app.Mix_NumEditField.Value = 5;
            app.FS_NumLabel = uilabel(app.FeatureSelMethodButtonGroup);
            app.FS_NumLabel.Text = 'FS_Num/Power(%):';
            app.FS_NumPowerEditField = uieditfield(app.FeatureSelMethodButtonGroup, 'numeric');
            app.FS_NumPowerEditField.ValueChangedFcn = createCallbackFcn(app, @FS_NumPowerEditFieldValueChanged, true);
            app.FS_NumPowerEditField.Value = 10;
            app.EditField_7 = uieditfield(app.FeatureSelMethodButtonGroup, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.PCAButton = uiradiobutton(app.FeatureSelMethodButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.NormMethodButtonGroup = uibuttongroup(app.Step2LayersandFeatureDetectionPanel);
            app.NormMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormMethodButtonGroupSelectionChanged, true);
            app.NormMethodButtonGroup.Title = 'Norm. Method';
            app.zscoreButton = uiradiobutton(app.NormMethodButtonGroup);
            app.zscoreButton.Text = 'zscore';
            app.zscoreButton.Value = true;
            app.rangeButton = uiradiobutton(app.NormMethodButtonGroup);
            app.rangeButton.Text = 'range';
            app.NormalizedFeaturesCheckBox = uicheckbox(app.Step2LayersandFeatureDetectionPanel);
            app.NormalizedFeaturesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizedFeaturesCheckBoxValueChanged, true);
            app.NormalizedFeaturesCheckBox.Text = 'Normalized Features';
            app.FeatureSelectionButton = uibutton(app.Step2LayersandFeatureDetectionPanel, 'push');
            app.FeatureSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureSelectionButtonPushed, true);
            app.FeatureSelectionButton.Text = 'Feature Selection';
            app.Step3TrainPanel = uipanel(app.TrainTestTab);
            app.Step3TrainPanel.Title = 'Step 3. Train';
            app.TestPartitionSliderLabel = uilabel(app.Step3TrainPanel);
            app.TestPartitionSliderLabel.Text = 'Test Partition (%)';
            app.TestPartitionSlider = uislider(app.Step3TrainPanel);
            app.TestPartitionSlider.ValueChangedFcn = createCallbackFcn(app, @TestPartitionSliderValueChanged, true);
            app.TestPartitionSlider.Value = 20;
            app.DecisionTreeCheckBox = uicheckbox(app.Step3TrainPanel);
            app.DecisionTreeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecisionTreeCheckBoxValueChanged, true);
            app.DecisionTreeCheckBox.Text = 'Decision Tree';
            app.DecisionTreeCheckBox.Value = true;
            app.SVMCheckBox = uicheckbox(app.Step3TrainPanel);
            app.SVMCheckBox.ValueChangedFcn = createCallbackFcn(app, @SVMCheckBoxValueChanged, true);
            app.SVMCheckBox.Text = 'SVM';
            app.SVMCheckBox.Value = true;
            app.KNNCheckBox = uicheckbox(app.Step3TrainPanel);
            app.KNNCheckBox.ValueChangedFcn = createCallbackFcn(app, @KNNCheckBoxValueChanged, true);
            app.KNNCheckBox.Text = 'KNN';
            app.KNNCheckBox.Value = true;
            app.NeuralNetworkCheckBox = uicheckbox(app.Step3TrainPanel);
            app.NeuralNetworkCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeuralNetworkCheckBoxValueChanged, true);
            app.NeuralNetworkCheckBox.Text = 'Neural Network';
            app.NeuralNetworkCheckBox.Value = true;
            app.RandomForestCheckBox = uicheckbox(app.Step3TrainPanel);
            app.RandomForestCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomForestCheckBoxValueChanged, true);
            app.RandomForestCheckBox.Text = 'Random Forest';
            app.RandomForestCheckBox.Value = true;
            app.naiveBayseCheckBox = uicheckbox(app.Step3TrainPanel);
            app.naiveBayseCheckBox.ValueChangedFcn = createCallbackFcn(app, @naiveBayseCheckBoxValueChanged, true);
            app.naiveBayseCheckBox.Text = 'naive Bayse';
            app.naiveBayseCheckBox.Value = true;
            app.RunTrainButton = uibutton(app.Step3TrainPanel, 'push');
            app.RunTrainButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrainButtonPushed, true);
            app.RunTrainButton.Text = 'Run Train';
            app.IterationNumberEditFieldLabel = uilabel(app.Step3TrainPanel);
            app.IterationNumberEditFieldLabel.Text = 'Iteration Number';
            app.IterationNumberEditField = uieditfield(app.Step3TrainPanel, 'numeric');
            app.IterationNumberEditField.ValueChangedFcn = createCallbackFcn(app, @IterationNumberEditFieldValueChanged, true);
            app.IterationNumberEditField.Value = 5;
            app.TestEnableCheckBox = uicheckbox(app.Step3TrainPanel);
            app.TestEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestEnableCheckBoxValueChanged, true);
            app.TestEnableCheckBox.Text = 'Test Enable';
            app.TestEnableCheckBox.Value = true;
            app.EnsembleModelCheckBox = uicheckbox(app.Step3TrainPanel);
            app.EnsembleModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnsembleModelCheckBoxValueChanged, true);
            app.EnsembleModelCheckBox.Text = 'Ensemble Model';
            app.EnsembleModelCheckBox.Value = true;
            app.EnableFeatureSelectionCheckBox = uicheckbox(app.Step3TrainPanel);
            app.EnableFeatureSelectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableFeatureSelectionCheckBoxValueChanged, true);
            app.EnableFeatureSelectionCheckBox.Text = 'Enable Feature Selection';
            app.ClassesNumPanel = uipanel(app.Step3TrainPanel);
            app.ClassesNumPanel.Title = 'Classes     (%)        Num.';
            app.NormalLabel = uilabel(app.ClassesNumPanel);
            app.NormalLabel.Text = 'Normal:';
            app.NormalEditField = uieditfield(app.ClassesNumPanel, 'numeric');
            app.NormalEditField.ValueChangedFcn = createCallbackFcn(app, @NormalEditFieldValueChanged, true);
            app.DMELabel = uilabel(app.ClassesNumPanel);
            app.DMELabel.Text = 'DME:';
            app.DMEEditField = uieditfield(app.ClassesNumPanel, 'numeric');
            app.DMEEditField.ValueChangedFcn = createCallbackFcn(app, @DMEEditFieldValueChanged, true);
            app.AMDLabel = uilabel(app.ClassesNumPanel);
            app.AMDLabel.Text = 'AMD:';
            app.AMDEditField = uieditfield(app.ClassesNumPanel, 'numeric');
            app.AMDEditField.ValueChangedFcn = createCallbackFcn(app, @AMDEditFieldValueChanged, true);
            app.CalButton = uibutton(app.ClassesNumPanel, 'push');
            app.CalButton.ButtonPushedFcn = createCallbackFcn(app, @CalButtonPushed, true);
            app.CalButton.Text = 'Cal.';
            app.BalanceClassesButton = uibutton(app.ClassesNumPanel, 'push');
            app.BalanceClassesButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceClassesButtonPushed, true);
            app.BalanceClassesButton.Text = 'Balance Classes';
            app.EditField_4 = uieditfield(app.ClassesNumPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.ClassesNumPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.ClassesNumPanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.Step1SelectDatabasePanel = uipanel(app.TrainTestTab);
            app.Step1SelectDatabasePanel.Title = 'Step 1. Select Database';
            app.DatabaseDropDownLabel = uilabel(app.Step1SelectDatabasePanel);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.Step1SelectDatabasePanel);
            app.DatabaseDropDown.Items = {'Read Duke DB', 'Load Duke DB'};
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.DatabaseDropDown.Value = 'Load Duke DB';
            app.CancelButton = uibutton(app.TrainTestTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.messageEditFieldLabel = uilabel(app.SiOCT);
            app.messageEditFieldLabel.Text = 'message:';
            app.messageEditField = uieditfield(app.SiOCT, 'text');
            app.messageEditField.ValueChangedFcn = createCallbackFcn(app, @messageEditFieldValueChanged, true);
            app.ContextMenu = uicontextmenu(app.SiOCT);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.FeatureSelMethodButtonGroup.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.SiOCT.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDOCT_main_D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SiOCT)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SiOCT)
        end
    end
end